classdef Particlefilter_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticleFilterUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ParticlesMeasurementPanel       matlab.ui.container.Panel
        InitializationTab               matlab.ui.container.Tab
        ChoosemapLabel                  matlab.ui.control.Label
        LoadMapButton                   matlab.ui.control.Button
        MapEditFieldLabel               matlab.ui.control.Label
        MapEditField                    matlab.ui.control.EditField
        StepsOrientationEditFieldLabel  matlab.ui.control.Label
        StepsOrientationEditField       matlab.ui.control.NumericEditField
        grid_xEditFieldLabel            matlab.ui.control.Label
        grid_xEditField                 matlab.ui.control.NumericEditField
        grid_yEditFieldLabel            matlab.ui.control.Label
        grid_yEditField                 matlab.ui.control.NumericEditField
        NumberofMatlabParallelpoolWorkerEditFieldLabel  matlab.ui.control.Label
        NumberofMatlabParallelpoolWorkerEditField  matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        MapResolutionEditFieldLabel     matlab.ui.control.Label
        MapResolutionEditField          matlab.ui.control.NumericEditField
        AngleRangeKinectradEditFieldLabel  matlab.ui.control.Label
        AngleRangeKinectradEditField    matlab.ui.control.NumericEditField
        NumberofIterationEditFieldLabel  matlab.ui.control.Label
        NumberofIterationEditField      matlab.ui.control.NumericEditField
        PointcloudPreparationLabel      matlab.ui.control.Label
        MatlabLabel                     matlab.ui.control.Label
        MeasurementTab                  matlab.ui.container.Tab
        ChooseyourDataLabel             matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        ProgramButtonGroup              matlab.ui.container.ButtonGroup
        SimulationButton                matlab.ui.control.RadioButton
        LoadMeasurementButton           matlab.ui.control.RadioButton
        TakeMeasurementButton           matlab.ui.control.RadioButton
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        RootFolderLabel                 matlab.ui.control.Label
        RootFolderEditField             matlab.ui.control.EditField
        MeasurementLabel                matlab.ui.control.Label
        ResamplingTab                   matlab.ui.container.Tab
        WeightingLabel                  matlab.ui.control.Label
        FunctionButtonGroup             matlab.ui.container.ButtonGroup
        KubicButton                     matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        SigmaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        MinNumberofParticlesEditFieldLabel  matlab.ui.control.Label
        MinNumberofParticlesEditField   matlab.ui.control.NumericEditField
        ResamplingLabel                 matlab.ui.control.Label
        DispersionXmEditFieldLabel      matlab.ui.control.Label
        DispersionXmEditField           matlab.ui.control.NumericEditField
        DispersionYmEditFieldLabel      matlab.ui.control.Label
        DispersionYmEditField           matlab.ui.control.NumericEditField
        DispersionOrientationradEditFieldLabel  matlab.ui.control.Label
        DispersionOrientationradEditField  matlab.ui.control.NumericEditField
        ReduceNumberParticlesEditFieldLabel  matlab.ui.control.Label
        ReduceNumberParticlesEditField  matlab.ui.control.NumericEditField
        MyEditFieldLabel                matlab.ui.control.Label
        MyEditField                     matlab.ui.control.NumericEditField
        SimulationTab                   matlab.ui.container.Tab
        XValueEditFieldLabel            matlab.ui.control.Label
        XValueEditField                 matlab.ui.control.NumericEditField
        YValueEditFieldLabel            matlab.ui.control.Label
        YValueEditField                 matlab.ui.control.NumericEditField
        OrientierungradEditFieldLabel   matlab.ui.control.Label
        OrientierungradEditField        matlab.ui.control.NumericEditField
        TestPoseLabel                   matlab.ui.control.Label
        MovementLabel                   matlab.ui.control.Label
        OEditFieldLabel                 matlab.ui.control.Label
        OEditField                      matlab.ui.control.NumericEditField
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        ResultsTab                      matlab.ui.container.Tab
        StartParticlefilterButton       matlab.ui.control.Button
        ProgressLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
        mapResolution % Resolution
        mapPath % Description
    end
    
    properties (Access = private)

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function AddButtonPushed(app, event)
      
        end

        % Size changed function: SimulationTab
        function SimulationTabSizeChanged(app, event)
           
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            [file,path] = uigetfile('*.*', 'Select File');
            string = strcat(path,file);            
            app.MapEditField.Value = string;
        end

        % Selection changed function: ProgramButtonGroup
        function ProgramButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProgramButtonGroup.SelectedObject;
            if (app.LoadMeasurementButton.Value == 1)
                set (app.LoadDataButton, 'Enable','on');
            else
                set (app.LoadDataButton, 'Enable','off');
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [~,path] = uigetfile('*.*', 'Choose your Data');
            [rootPath,b,~] = fileparts(fileparts(path));       
%             disp(rootPath);
            app.RootFolderEditField.Value = rootPath;
            app.DataEditField.Value = b;
        end

        % Button pushed function: StartParticlefilterButton
        function StartParticlefilterButtonPushed(app, event)
            % Simulate / Load / Measure
            % weighting Fctn
            function TestCallBack(iteration)
%                 app.NumberofIterationEditField.Value = iteration;
                app.ProgressLabel.Text = [num2str(iteration / (app.NumberofIterationEditField.Value-1) * 100) '%; (Iteration: ' num2str(iteration) ')' ];
                drawnow;
            end
            
            
            if (app.SimulationButton.Value)
                simFlag = 'sim';
            elseif (app.LoadMeasurementButton.Value)
                simFlag = app.DataEditField.Value;
            else 
                simFlag = 'Take';
            end

            UIAxesStats = struct(...
                'LeftAxes' , app.UIAxes3, ...
                'MiddleAxes' , app.UIAxes4, ...
                'RightAxes', app.UIAxes5 ...
            );
            
            StartParticlefilter( ...                
                @TestCallBack,      ...
                'AxesStats',        UIAxesStats, ...
                'AxesParticle',     app.ParticlesMeasurementPanel, ...
                'mapPath',          app.MapEditField.Value, ...
                'mapResolution',    app.MapResolutionEditField.Value, ...
                'numWorkers',       app.NumberofMatlabParallelpoolWorkerEditField.Value, ...
                'pose',             [app.XValueEditField.Value, app.YValueEditField.Value, app.OrientierungradEditField.Value], ...
                'movement',         [app.OEditField.Value, app.DistanceEditField.Value], ...
                'sigma',            app.SigmaEditField.Value, ...
                'my',               app.MyEditField.Value, ...
                'minNumParticles',  app.MinNumberofParticlesEditField.Value, ...
                'bins',             app.NumberofBinsEditField.Value, ...
                ... % 'minY', app., ...
                ... % 'maxY', ...
                'dispersion',       [app.DispersionXmEditField.Value, app.DispersionYmEditField.Value, app.DispersionOrientationradEditField.Value], ...
                ... % 'factorParticleReduction', ...
                'mapResolution',    app.MapResolutionEditField.Value, ...
                'mapPath',          app.MapEditField.Value, ...
                'gridX',            app.grid_xEditField.Value, ...
                'gridY',            app.grid_yEditField.Value, ...
                'maxIteration',     app.NumberofIterationEditField.Value, ...
                'angleRange',       app.AngleRangeKinectradEditField.Value, ...
                'simFlag',          simFlag, ...
                'stepsOrientation', app.StepsOrientationEditField.Value, ...
                'dataRootFolder',   app.RootFolderEditField.Value ...
            );
        end

        % Value changed function: MapResolutionEditField
        function MapResolutionEditFieldValueChanged(app, event)
            app.mapResolution = app.MapResolutionEditField.Value;            
        end

        % Value changed function: MapEditField
        function MapEditFieldValueChanged(app, event)
            app.mapPath = app.MapEditField.Value;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParticleFilterUIFigure = uifigure;
            app.ParticleFilterUIFigure.Name = 'ParticleFilter';
            app.TabGroup = uitabgroup(app.ParticleFilterUIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.UIAxes3 = uiaxes(app.OverviewTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.OverviewTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.OverviewTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.ParticlesMeasurementPanel = uipanel(app.OverviewTab);
            app.ParticlesMeasurementPanel.Title = 'Particles & Measurement:';
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.ChoosemapLabel = uilabel(app.InitializationTab);
            app.ChoosemapLabel.Text = 'Choose map:';
            app.LoadMapButton = uibutton(app.InitializationTab, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'Load Map';
            app.MapEditFieldLabel = uilabel(app.InitializationTab);
            app.MapEditFieldLabel.Text = 'Map:';
            app.MapEditField = uieditfield(app.InitializationTab, 'text');
            app.MapEditField.ValueChangedFcn = createCallbackFcn(app, @MapEditFieldValueChanged, true);
            app.MapEditField.Editable = 'off';
            app.MapEditField.Value = 'D:\Matlab_Nav_App\2019\Data\Vorgabe_Rundgang.png';
            app.StepsOrientationEditFieldLabel = uilabel(app.InitializationTab);
            app.StepsOrientationEditFieldLabel.Text = 'Steps Orientation';
            app.StepsOrientationEditField = uieditfield(app.InitializationTab, 'numeric');
            app.StepsOrientationEditField.Limits = [1 Inf];
            app.StepsOrientationEditField.Value = 31;
            app.grid_xEditFieldLabel = uilabel(app.InitializationTab);
            app.grid_xEditFieldLabel.Text = 'grid_x';
            app.grid_xEditField = uieditfield(app.InitializationTab, 'numeric');
            app.grid_xEditField.LowerLimitInclusive = 'off';
            app.grid_xEditField.Limits = [0 Inf];
            app.grid_xEditField.Value = 1;
            app.grid_yEditFieldLabel = uilabel(app.InitializationTab);
            app.grid_yEditFieldLabel.Text = 'grid_y';
            app.grid_yEditField = uieditfield(app.InitializationTab, 'numeric');
            app.grid_yEditField.LowerLimitInclusive = 'off';
            app.grid_yEditField.Limits = [0 Inf];
            app.grid_yEditField.Value = 1;
            app.NumberofMatlabParallelpoolWorkerEditFieldLabel = uilabel(app.InitializationTab);
            app.NumberofMatlabParallelpoolWorkerEditFieldLabel.Text = 'Number of Matlab Parallelpool Worker:';
            app.NumberofMatlabParallelpoolWorkerEditField = uieditfield(app.InitializationTab, 'numeric');
            app.NumberofMatlabParallelpoolWorkerEditField.Limits = [1 Inf];
            app.NumberofMatlabParallelpoolWorkerEditField.Value = 4;
            app.NumberofBinsEditFieldLabel = uilabel(app.InitializationTab);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.InitializationTab, 'numeric');
            app.NumberofBinsEditField.Limits = [1 Inf];
            app.NumberofBinsEditField.Value = 20;
            app.MapResolutionEditFieldLabel = uilabel(app.InitializationTab);
            app.MapResolutionEditFieldLabel.Text = 'Map Resolution';
            app.MapResolutionEditField = uieditfield(app.InitializationTab, 'numeric');
            app.MapResolutionEditField.Limits = [1 Inf];
            app.MapResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @MapResolutionEditFieldValueChanged, true);
            app.MapResolutionEditField.Value = 20;
            app.AngleRangeKinectradEditFieldLabel = uilabel(app.InitializationTab);
            app.AngleRangeKinectradEditFieldLabel.Text = 'Angle Range (Kinect) [rad]';
            app.AngleRangeKinectradEditField = uieditfield(app.InitializationTab, 'numeric');
            app.AngleRangeKinectradEditField.Limits = [0 7];
            app.AngleRangeKinectradEditField.Value = 0.96;
            app.NumberofIterationEditFieldLabel = uilabel(app.InitializationTab);
            app.NumberofIterationEditFieldLabel.Text = 'Number of Iteration';
            app.NumberofIterationEditField = uieditfield(app.InitializationTab, 'numeric');
            app.NumberofIterationEditField.Limits = [1 Inf];
            app.NumberofIterationEditField.Value = 10;
            app.PointcloudPreparationLabel = uilabel(app.InitializationTab);
            app.PointcloudPreparationLabel.Text = 'Pointcloud Preparation:';
            app.MatlabLabel = uilabel(app.InitializationTab);
            app.MatlabLabel.Text = 'Matlab:';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.ChooseyourDataLabel = uilabel(app.MeasurementTab);
            app.ChooseyourDataLabel.Text = 'Choose your Data:';
            app.LoadDataButton = uibutton(app.MeasurementTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.ProgramButtonGroup = uibuttongroup(app.MeasurementTab);
            app.ProgramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramButtonGroupSelectionChanged, true);
            app.ProgramButtonGroup.Title = 'Program';
            app.SimulationButton = uiradiobutton(app.ProgramButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.LoadMeasurementButton = uiradiobutton(app.ProgramButtonGroup);
            app.LoadMeasurementButton.Text = 'Load Measurement';
            app.TakeMeasurementButton = uiradiobutton(app.ProgramButtonGroup);
            app.TakeMeasurementButton.Text = 'Take Measurement';
            app.DataEditFieldLabel = uilabel(app.MeasurementTab);
            app.DataEditFieldLabel.Text = 'Data:';
            app.DataEditField = uieditfield(app.MeasurementTab, 'text');
            app.RootFolderLabel = uilabel(app.MeasurementTab);
            app.RootFolderLabel.Text = 'Root Folder:';
            app.RootFolderEditField = uieditfield(app.MeasurementTab, 'text');
            app.MeasurementLabel = uilabel(app.MeasurementTab);
            app.MeasurementLabel.Text = 'Measurement:';
            app.ResamplingTab = uitab(app.TabGroup);
            app.ResamplingTab.Title = 'Resampling';
            app.WeightingLabel = uilabel(app.ResamplingTab);
            app.WeightingLabel.Text = 'Weighting:';
            app.FunctionButtonGroup = uibuttongroup(app.ResamplingTab);
            app.FunctionButtonGroup.Title = 'Function';
            app.KubicButton = uiradiobutton(app.FunctionButtonGroup);
            app.KubicButton.Text = 'Kubic';
            app.KubicButton.Value = true;
            app.LinearButton = uiradiobutton(app.FunctionButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.SigmaEditFieldLabel = uilabel(app.ResamplingTab);
            app.SigmaEditFieldLabel.Text = 'Sigma:';
            app.SigmaEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 0.5;
            app.MinNumberofParticlesEditFieldLabel = uilabel(app.ResamplingTab);
            app.MinNumberofParticlesEditFieldLabel.Text = 'Min Number of Particles:';
            app.MinNumberofParticlesEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.MinNumberofParticlesEditField.Limits = [1 Inf];
            app.MinNumberofParticlesEditField.Value = 1500;
            app.ResamplingLabel = uilabel(app.ResamplingTab);
            app.ResamplingLabel.Text = 'Resampling:';
            app.DispersionXmEditFieldLabel = uilabel(app.ResamplingTab);
            app.DispersionXmEditFieldLabel.Text = 'Dispersion X [m]:';
            app.DispersionXmEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.DispersionXmEditField.Limits = [0 Inf];
            app.DispersionXmEditField.Value = 0.5;
            app.DispersionYmEditFieldLabel = uilabel(app.ResamplingTab);
            app.DispersionYmEditFieldLabel.Text = 'Dispersion Y [m]:';
            app.DispersionYmEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.DispersionYmEditField.Limits = [0 Inf];
            app.DispersionYmEditField.Value = 0.5;
            app.DispersionOrientationradEditFieldLabel = uilabel(app.ResamplingTab);
            app.DispersionOrientationradEditFieldLabel.Text = 'Dispersion Orientation [rad]:';
            app.DispersionOrientationradEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.DispersionOrientationradEditField.Limits = [0 7];
            app.DispersionOrientationradEditField.Value = 0.0491;
            app.ReduceNumberParticlesEditFieldLabel = uilabel(app.ResamplingTab);
            app.ReduceNumberParticlesEditFieldLabel.Text = 'Reduce Number Particles';
            app.ReduceNumberParticlesEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.ReduceNumberParticlesEditField.Limits = [0.1 1];
            app.ReduceNumberParticlesEditField.Value = 0.95;
            app.MyEditFieldLabel = uilabel(app.ResamplingTab);
            app.MyEditFieldLabel.Text = 'My:';
            app.MyEditField = uieditfield(app.ResamplingTab, 'numeric');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.SizeChangedFcn = createCallbackFcn(app, @SimulationTabSizeChanged, true);
            app.SimulationTab.Title = 'Simulation';
            app.XValueEditFieldLabel = uilabel(app.SimulationTab);
            app.XValueEditFieldLabel.Text = 'X-Value:';
            app.XValueEditField = uieditfield(app.SimulationTab, 'numeric');
            app.YValueEditFieldLabel = uilabel(app.SimulationTab);
            app.YValueEditFieldLabel.Text = 'Y-Value:';
            app.YValueEditField = uieditfield(app.SimulationTab, 'numeric');
            app.OrientierungradEditFieldLabel = uilabel(app.SimulationTab);
            app.OrientierungradEditFieldLabel.Text = 'Orientierung [rad]:';
            app.OrientierungradEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TestPoseLabel = uilabel(app.SimulationTab);
            app.TestPoseLabel.Text = 'Test-Pose:';
            app.MovementLabel = uilabel(app.SimulationTab);
            app.MovementLabel.Text = 'Movement:';
            app.OEditFieldLabel = uilabel(app.SimulationTab);
            app.OEditFieldLabel.Text = 'O'':';
            app.OEditField = uieditfield(app.SimulationTab, 'numeric');
            app.OEditField.Limits = [0 Inf];
            app.DistanceEditFieldLabel = uilabel(app.SimulationTab);
            app.DistanceEditFieldLabel.Text = 'Distance:';
            app.DistanceEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DistanceEditField.Limits = [0 Inf];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.StartParticlefilterButton = uibutton(app.ParticleFilterUIFigure, 'push');
            app.StartParticlefilterButton.ButtonPushedFcn = createCallbackFcn(app, @StartParticlefilterButtonPushed, true);
            app.StartParticlefilterButton.Text = 'Start Particlefilter';
            app.ProgressLabel = uilabel(app.ParticleFilterUIFigure);
            app.ProgressLabel.Text = 'Progress...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Particlefilter_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticleFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticleFilterUIFigure)
        end
    end
end